classdef encontrar_raiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_5                       matlab.ui.container.Panel
        UIAxes_3                      matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        Panel_2                       matlab.ui.container.Panel
        ToleranciaEditField           matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel      matlab.ui.control.Label
        encontrarraicesLabel          matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        ConocermasButton              matlab.ui.control.Button
        MtodosparaLabel               matlab.ui.control.Label
        IntroducelafuncionEditField   matlab.ui.control.EditField
        IntroducelafuncionEditFieldLabel  matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        PuntodeterminoEditField       matlab.ui.control.NumericEditField
        PuntodeterminoEditFieldLabel  matlab.ui.control.Label
        PuntodeinicioEditField        matlab.ui.control.NumericEditField
        PuntodeinicioEditFieldLabel   matlab.ui.control.Label
        SeleccionaelmetodoDropDown    matlab.ui.control.DropDown
        SeleccionaelmetodoDropDownLabel  matlab.ui.control.Label
        IteracionesEditField          matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel     matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metodo)
            app.SeleccionaelmetodoDropDown.Value = metodo;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            a = app.PuntodeinicioEditField.Value;
            b = app.PuntodeterminoEditField.Value;
            f = str2sym(app.IntroducelafuncionEditField.Value);
            i = app.IteracionesEditField.Value;
            e = app.ToleranciaEditField.Value;
            metodo = app.SeleccionaelmetodoDropDown.Value;
            switch metodo
                case "Biseccion"
                    resultados = biseccion(a,b,i,e,f);
                case "Falsa posicion"
                    resultados = falsaposicion(a,b,i,e,f);
                case "Recta secante"
                    resultados = rectasecante(a,b,i,e,f);
                case "Newton-Raphson"
                    resultados = newtonraphson(a,i,e,f);
                case "Punto fijo"
                    resultados = puntofijo(a,i,e,f);
            end

           app.UITable.Data = resultados;
           cla(app.UIAxes)
           cla(app.UIAxes_2)
           cla(app.UIAxes_3)
           title(app.UIAxes_2,'Iteracion vs Aproximacion')
           title(app.UIAxes_3,'Iteracion vs Error')
           title(app.UIAxes,'Funcion')
           app.UITable.ColumnFormat = {'short','long','long'};
           vars = symvar(f);
           sol1 = resultados(end,2);
           %Gráfico aproximación - error vs aproximaciones
            %Valor real y aproximación
            xlabel(app.UIAxes_2,'Iteración');
            grid(app.UIAxes_2,"on");
            x1=[0,numel(resultados(:,1))-1];
            y1=[sol1,sol1];
            ylabel(app.UIAxes_2,'Aproximación');
            hold(app.UIAxes_2,'on');
    
            %Aproximación
            plot(app.UIAxes_2,resultados(:,1)',resultados(:,2)','o','LineWidth',2);
    
            %Exacta
            plot(app.UIAxes_2,x1,y1,'--',"Color","r");
            hold(app.UIAxes_2,'off');
            legend(app.UIAxes_2,{'Aproximaciones por metodo numerico','Solución según sistema'},'Location','best');
            
            %Gráfico error
            xlabel(app.UIAxes_3,'Iteración');
            grid(app.UIAxes_3,"on");
            ylabel(app.UIAxes_3,'Error');
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,resultados(:,1)',resultados(:,3)','o','LineWidth',2);
            hold(app.UIAxes_3,'off');
            legend(app.UIAxes_3,{'Error'},'Location','best');
    
            %Gráfica función
            xlabel(app.UIAxes,'Eje X'); 
            ylabel(app.UIAxes,'Eje Y');
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            %Función
            x2=sol1(1)-0.75*abs(sol1(1)):0.01:sol1(1)+0.75*abs(sol1(1));
            y2=double(subs(f,vars(1),x2));
            plot(app.UIAxes,x2,y2,'LineWidth',2);
            plot(app.UIAxes,vpasolve(f,vars(1)),double(subs(f,vars(1),vpasolve(f,vars(1)))),"Color",'r',"Marker","*","MarkerSize",15);
            %Ejes coordenados
            xline(app.UIAxes,0); 
            yline(app.UIAxes,0);
            hold(app.UIAxes,"off");
            legend(app.UIAxes,{'Función','Solución'},'Location','best');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Funcion vs Interpolacion')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.IteracionesEditFieldLabel = uilabel(app.Panel_2);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.Panel_2, 'numeric');
            app.SeleccionaelmetodoDropDownLabel = uilabel(app.Panel_2);
            app.SeleccionaelmetodoDropDownLabel.Text = 'Selecciona el metodo';
            app.SeleccionaelmetodoDropDown = uidropdown(app.Panel_2);
            app.SeleccionaelmetodoDropDown.Items = {'Biseccion', 'Newton-Raphson', 'Falsa posicion', 'Recta secante', 'Punto fijo'};
            app.SeleccionaelmetodoDropDown.Value = 'Biseccion';
            app.PuntodeinicioEditFieldLabel = uilabel(app.Panel_2);
            app.PuntodeinicioEditFieldLabel.Text = 'Punto de inicio';
            app.PuntodeinicioEditField = uieditfield(app.Panel_2, 'numeric');
            app.PuntodeterminoEditFieldLabel = uilabel(app.Panel_2);
            app.PuntodeterminoEditFieldLabel.Text = 'Punto de termino';
            app.PuntodeterminoEditField = uieditfield(app.Panel_2, 'numeric');
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IntroducelafuncionEditFieldLabel = uilabel(app.Panel_2);
            app.IntroducelafuncionEditFieldLabel.Text = 'Introduce la funcion';
            app.IntroducelafuncionEditField = uieditfield(app.Panel_2, 'text');
            app.MtodosparaLabel = uilabel(app.Panel_2);
            app.MtodosparaLabel.Text = 'Métodos para';
            app.ConocermasButton = uibutton(app.Panel_2, 'push');
            app.ConocermasButton.Text = 'Conocer mas...';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Text = '';
            app.encontrarraicesLabel = uilabel(app.Panel_2);
            app.encontrarraicesLabel.Text = 'encontrar raices';
            app.ToleranciaEditFieldLabel = uilabel(app.Panel_2);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Iteracion'; 'x'; 'y'};
            app.UITable.RowName = {};
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_4);
            title(app.UIAxes_2, 'Iteracion vs Aproximacion')
            xlabel(app.UIAxes_2, 'Eje X')
            ylabel(app.UIAxes_2, 'Eje Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_5 = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.Panel_5);
            title(app.UIAxes_3, 'Funcion vs Interpolacion')
            xlabel(app.UIAxes_3, 'Eje X')
            ylabel(app.UIAxes_3, 'Eje Y')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encontrar_raiz(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end